classdef responsePadTab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ShowtheDogwherethecolourobjectisLabel  matlab.ui.control.Label
        WhiteButton  matlab.ui.control.Button
        RedButton    matlab.ui.control.Button
        PinkButton   matlab.ui.control.Button
        GreenButton  matlab.ui.control.Button
        BlueButton   matlab.ui.control.Button
        BlackButton  matlab.ui.control.Button
        PinButton    matlab.ui.control.Button
        PenButton    matlab.ui.control.Button
        PegButton    matlab.ui.control.Button
        PearButton   matlab.ui.control.Button
        OatButton    matlab.ui.control.Button
        OakButton    matlab.ui.control.Button
        LockButton   matlab.ui.control.Button
        ConeButton   matlab.ui.control.Button
        CoatButton   matlab.ui.control.Button
        ClockButton  matlab.ui.control.Button
        CloakButton  matlab.ui.control.Button
        BowlButton   matlab.ui.control.Button
        BoneButton   matlab.ui.control.Button
        BoatButton   matlab.ui.control.Button
        BinButton    matlab.ui.control.Button
        BedButton    matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
      ResponseColour;
      ResponseNoun;% Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui('center');
            
%             % display the specific animal picture desired
%             axes(AnimalImage);
%             % set(gca,'DefaultImageVisible','off')
%             image(AnimalImage)
%             set(handles.axes1, 'Visible', 'off');
%             if trial == 1 % the first trial
%                 pause(2)
%             end
%             set(0,'showHiddenHandles','on');
%            
            
            imshow('Bouncy.jpg','parent',app.UIAxes)
            
            set(app.UIAxes,'visible','off');
           
        end

        % Callback function
        function CoatButtonPushed(app, event)
            app.ResponseNoun.Value = 'coat';
            uiresume(app.UIFigure)
        end

        % Button pushed function: BlackButton
        function BlackButtonPushed(app, event)
            app.ResponseColour.Value = 'black';
        end

        % Button pushed function: WhiteButton
        function WhiteButtonPushed(app, event)
            app.ResponseColour.Value = 'white';
        end

        % Button pushed function: RedButton
        function RedButtonPushed(app, event)
            app.ResponseColour.Value = 'red';
        end

        % Button pushed function: PinkButton
        function PinkButtonPushed(app, event)
            app.ResponseColour.Value = 'pink';
        end

        % Button pushed function: GreenButton
        function GreenButtonPushed(app, event)
            app.ResponseColour.Value = 'green';
        end

        % Button pushed function: BlueButton
        function BlueButtonPushed(app, event)
            app.ResponseColour.Value = 'blue';
        end

        % Button pushed function: BedButton
        function BedButtonPushed(app, event)
            app.ResponseNoun.Value = 'bed';
            uiresume(app.UIFigure)
           
        end

        % Button pushed function: BinButton
        function BinButtonPushed(app, event)
            app.ResponseNoun.Value = 'bin';
            uiresume(app.UIFigure)
            
        end

        % Button pushed function: BoatButton
        function BoatButtonPushed(app, event)
            app.ResponseNoun.Value = 'boat';
            uiresume(app.UIFigure)
           
        end

        % Button pushed function: BoneButton
        function BoneButtonPushed(app, event)
            app.ResponseNoun.Value= 'bone';
            uiresume(app.UIFigure)
           
        end

        % Button pushed function: BowlButton
        function BowlButtonPushed(app, event)
            app.ResponseNoun.Value = 'bowl';
            uiresume(app.UIFigure)
           
        end

        % Button pushed function: CloakButton
        function CloakButtonPushed(app, event)
            app.ResponseNoun.Value = 'cloak';
            uiresume(app.UIFigure)
        end

        % Button pushed function: ClockButton, CoatButton
        function ClockButtonPushed(app, event)
            app.ResponseNoun.Value = 'clock';
            uiresume(app.UIFigure)
        end

        % Button pushed function: ConeButton
        function ConeButtonPushed(app, event)
            app.ResponseNoun.Value = 'cone';
            uiresume(app.UIFigure)
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            app.ResponseNoun.Value = 'lock';
            uiresume(app.UIFigure)
        end

        % Button pushed function: OakButton
        function OakButtonPushed(app, event)
            app.ResponseNoun.Value = 'oak';
            uiresume(app.UIFigure)
        end

        % Button pushed function: OatButton
        function OatButtonPushed(app, event)
            app.ResponseNoun.Value = 'oat';
            uiresume(app.UIFigure)
        end

        % Button pushed function: PearButton
        function PearButtonPushed(app, event)
            app.ResponseNoun.Value = 'pear';
            uiresume(app.UIFigure)
        end

        % Button pushed function: PegButton
        function PegButtonPushed(app, event)
            app.ResponseNoun.Value = 'peg';
            uiresume(app.UIFigure)
        end

        % Button pushed function: PenButton
        function PenButtonPushed(app, event)
            app.ResponseNoun.Value = 'pen';
            uiresume(app.UIFigure)
        end

        % Button pushed function: PinButton
        function PinButtonPushed(app, event)
            app.ResponseNoun.Value = 'pin';
            uiresume(app.UIFigure)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.BedButton = uibutton(app.UIFigure, 'push');
            app.BedButton.ButtonPushedFcn = createCallbackFcn(app, @BedButtonPushed, true);
            app.BedButton.Text = 'Bed';
            app.BinButton = uibutton(app.UIFigure, 'push');
            app.BinButton.ButtonPushedFcn = createCallbackFcn(app, @BinButtonPushed, true);
            app.BinButton.Text = 'Bin';
            app.BoatButton = uibutton(app.UIFigure, 'push');
            app.BoatButton.ButtonPushedFcn = createCallbackFcn(app, @BoatButtonPushed, true);
            app.BoatButton.Text = 'Boat';
            app.BoneButton = uibutton(app.UIFigure, 'push');
            app.BoneButton.ButtonPushedFcn = createCallbackFcn(app, @BoneButtonPushed, true);
            app.BoneButton.Text = 'Bone';
            app.BowlButton = uibutton(app.UIFigure, 'push');
            app.BowlButton.ButtonPushedFcn = createCallbackFcn(app, @BowlButtonPushed, true);
            app.BowlButton.Text = 'Bowl';
            app.CloakButton = uibutton(app.UIFigure, 'push');
            app.CloakButton.ButtonPushedFcn = createCallbackFcn(app, @CloakButtonPushed, true);
            app.CloakButton.Text = 'Cloak';
            app.ClockButton = uibutton(app.UIFigure, 'push');
            app.ClockButton.ButtonPushedFcn = createCallbackFcn(app, @ClockButtonPushed, true);
            app.ClockButton.Text = 'Clock';
            app.CoatButton = uibutton(app.UIFigure, 'push');
            app.CoatButton.ButtonPushedFcn = createCallbackFcn(app, @ClockButtonPushed, true);
            app.CoatButton.Text = 'Coat';
            app.ConeButton = uibutton(app.UIFigure, 'push');
            app.ConeButton.ButtonPushedFcn = createCallbackFcn(app, @ConeButtonPushed, true);
            app.ConeButton.Text = 'Cone';
            app.LockButton = uibutton(app.UIFigure, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Text = 'Lock';
            app.OakButton = uibutton(app.UIFigure, 'push');
            app.OakButton.ButtonPushedFcn = createCallbackFcn(app, @OakButtonPushed, true);
            app.OakButton.Text = 'Oak';
            app.OatButton = uibutton(app.UIFigure, 'push');
            app.OatButton.ButtonPushedFcn = createCallbackFcn(app, @OatButtonPushed, true);
            app.OatButton.Text = 'Oat';
            app.PearButton = uibutton(app.UIFigure, 'push');
            app.PearButton.ButtonPushedFcn = createCallbackFcn(app, @PearButtonPushed, true);
            app.PearButton.Text = 'Pear';
            app.PegButton = uibutton(app.UIFigure, 'push');
            app.PegButton.ButtonPushedFcn = createCallbackFcn(app, @PegButtonPushed, true);
            app.PegButton.Text = 'Peg';
            app.PenButton = uibutton(app.UIFigure, 'push');
            app.PenButton.ButtonPushedFcn = createCallbackFcn(app, @PenButtonPushed, true);
            app.PenButton.Text = 'Pen';
            app.PinButton = uibutton(app.UIFigure, 'push');
            app.PinButton.ButtonPushedFcn = createCallbackFcn(app, @PinButtonPushed, true);
            app.PinButton.Text = 'Pin';
            app.BlackButton = uibutton(app.UIFigure, 'push');
            app.BlackButton.ButtonPushedFcn = createCallbackFcn(app, @BlackButtonPushed, true);
            app.BlackButton.Text = 'Black';
            app.BlueButton = uibutton(app.UIFigure, 'push');
            app.BlueButton.ButtonPushedFcn = createCallbackFcn(app, @BlueButtonPushed, true);
            app.BlueButton.Text = 'Blue';
            app.GreenButton = uibutton(app.UIFigure, 'push');
            app.GreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenButtonPushed, true);
            app.GreenButton.Text = 'Green';
            app.PinkButton = uibutton(app.UIFigure, 'push');
            app.PinkButton.ButtonPushedFcn = createCallbackFcn(app, @PinkButtonPushed, true);
            app.PinkButton.Text = 'Pink';
            app.RedButton = uibutton(app.UIFigure, 'push');
            app.RedButton.ButtonPushedFcn = createCallbackFcn(app, @RedButtonPushed, true);
            app.RedButton.Text = 'Red';
            app.WhiteButton = uibutton(app.UIFigure, 'push');
            app.WhiteButton.ButtonPushedFcn = createCallbackFcn(app, @WhiteButtonPushed, true);
            app.WhiteButton.Text = 'White';
            app.ShowtheDogwherethecolourobjectisLabel = uilabel(app.UIFigure);
            app.ShowtheDogwherethecolourobjectisLabel.Text = 'Show the Dog where the [colour], [object] is!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = responsePadTab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end